classdef FUCCI_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                    matlab.ui.Figure
        LoadDataMenu                  matlab.ui.container.Menu
        RawdataxlsxcsvMenu            matlab.ui.container.Menu
        open_classified               matlab.ui.container.Menu
        ImagefilestiffMenu            matlab.ui.container.Menu
        SavetofileMenu                matlab.ui.container.Menu
        ClassifieddataxlsxMenu        matlab.ui.container.Menu
        SiRDNAimagePanel              matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        AddCommentButton              matlab.ui.control.Button
        UpdateImage                   matlab.ui.control.Button
        ForwardButton                 matlab.ui.control.Button
        FrameNumberImage              matlab.ui.control.NumericEditField
        FrameLabel                    matlab.ui.control.Label
        CommentEditField              matlab.ui.control.EditField
        CommentEditFieldLabel         matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        ImagePlot                     matlab.ui.control.UIAxes
        CellSelector                  matlab.ui.control.ListBox
        Save_data                     matlab.ui.control.Button
        TrackSelector                 matlab.ui.control.DropDown
        AutomaticcharacterisationsettingsPanel  matlab.ui.container.Panel
        GridACSPanel                  matlab.ui.container.GridLayout
        edit1                         matlab.ui.control.EditField
        edit2                         matlab.ui.control.EditField
        edit3                         matlab.ui.control.EditField
        text2                         matlab.ui.control.Label
        text3                         matlab.ui.control.Label
        text4                         matlab.ui.control.Label
        UncertaintyCheckbox           matlab.ui.control.CheckBox
        ChangebeforeloadingdataLabel  matlab.ui.control.Label
        edit4                         matlab.ui.control.EditField
        text4_2                       matlab.ui.control.Label
        ManualcharacterisationupdatePanel  matlab.ui.container.Panel
        pushbutton4                   matlab.ui.control.Button
        text8                         matlab.ui.control.Label
        text7                         matlab.ui.control.Label
        edit8                         matlab.ui.control.EditField
        edit7                         matlab.ui.control.EditField
        listbox2                      matlab.ui.control.ListBox
        SelecttracknumberLabel        matlab.ui.control.Label
        SelectcellnumberLabel         matlab.ui.control.Label
        IntensityPlot                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Pre-defined properties of the PIP-FUCCI tool. All app properties
        % need a definition here. Their value or type can be changed later.
        
        % The different phases and values used for them
        phases = ["Uncertain", "M", "G1", "S", "G2"]
        phaseval = [0, 0.25, 0.5, 0.75, 1]
        
        % Logical toggles
        data_loaded = 0
        image_loaded = 0
        data_saved = 0
        transfer_coords = 0;
        
        % Data
        NUM = []
        max_frame = 0
        comments = [];
        
        % Image stuff
        Image = []             % Tiff image chosen by user with ImagefiletiffMenu
        ImageInfo = []         % Information of this tiff file
        ImageWidth = 1159.400  % Width of image
        ImageHeight = 1164.330 % Height of image
        ImageContrast = 0.05   % Image contrast setting
        CellOffset = 50.000    % Offset around a cell
    end
    
    methods (Access = private)
        function slope = calc_slope(app, channel)
            slope = gradient(channel);
        end
        
        function classified = classify_stages(app, params, green_norm, red_norm, white_norm)
            % Function to classify the stages in the input channel.
            % Normalized AND filtered channels are used as input.
            
            % Pre-allocate classfied array.
            classified = zeros(size(green_norm));
            
            % Calculate slopes
            green_Slope = calc_slope(app, green_norm);
            red_Slope   = calc_slope(app, red_norm);
            % white_Slope = calc_slope(app, white_norm);
            
            % Get classes
            M  = app.phaseval(app.phases=='M');
            G1 = app.phaseval(app.phases=='G1');
            S  = app.phaseval(app.phases=='S');
            G2 = app.phaseval(app.phases=='G2');
            
            % Get parameters
            interval = params.time_interval_mitosis;
            gradual  = params.thresh_gradual;
            steep    = params.thresh_steep;
            minima   = params.thresh_minima;
            
            % Calculate minima
            red_min = (1 + minima)*min(red_norm);
            green_min = (1 + minima).*min(green_norm);
  
            % Find G1 phase (green steep dip)
            classified(red_Slope < gradual & green_Slope < -gradual) = G1;
            
            % Find G2 phase (both grow)
            classified(red_Slope > gradual & green_Slope > gradual) = G2;
            
            % Find S phase (red increases while green does not)
            classified(red_Slope > gradual*4 & green_Slope < gradual) = S;
                        
            % Find mitosis (red steep dip)
            classified(red_Slope < steep) = M;
            
            % Connect close mitoses
            idx = find(classified == M);
            didx = diff(idx);
            for i=1:length(didx)
                if didx(i) > 1 && didx(i) < interval
                    classified(idx(i):idx(i+1)) = M;
                end
            end
                        
%             % Fill in holes
%             lastClass = classified(1);
%             for i=2:length(classified)
%                 if classified(i) == 0
%                     % Unclassified
%                     switch lastClass
%                         case G1
%                             classified(i) = S;
%                         case M
%                             classified(i) = G1;
%                         case G2
%                             classified(i) = G2;
%                         case S
%                             classified(i) = S; 
%                     end
%                 else
%                     % Already classified
%                     lastClass = classified(i);
%                 end
%             end
        end

%         function classified = classify_stages_old(app, params, green_norm, red_norm, white_norm)
%             % Function to classify the stages in channels. White channels
%             % are not used for classification yet.
%             % Input: normalized channels, output: classification
%             
%             % Calculate slopes
%             green_Slope = calc_slope(app, green_norm);
%             red_Slope   = calc_slope(app, red_norm);
%             white_Slope = calc_slope(app, white_norm);
%             
%             % Get parameters
%             thresh_gradual        = params.thresh_gradual;
%             thresh_steep          = params.thresh_steep;
%             time_interval_mitosis = params.time_interval_mitosis;
%             
%             % Pre-allocate classification
%             classified=zeros(size(green_Slope));
%             
%             % find mitosis
%             a0=find(red_Slope<thresh_steep);
%             classified(a0)=0.25;
%             
%             % find beginning of all mitosis events
%             if ~isempty(a0)
%                 a00=find(diff(a0)>1);
%                 
%                 mitosis_begin=[a0(1) a0(a00+1)];
%                 if length(mitosis_begin)>1
%                     mitosis_begin2=mitosis_begin(1);
%                     for ii=1:length(mitosis_begin)-1
%                         if mitosis_begin(ii+1)-mitosis_begin(ii)>time_interval_mitosis
%                             mitosis_begin2=[mitosis_begin2 mitosis_begin(ii+1)];
%                         else
%                             classified(mitosis_begin(ii):mitosis_begin(ii+1))=0.25;
%                         end
%                     end
%                     mitosis_begin=mitosis_begin2;
%                     mitosis_end=[mitosis_begin(2:end) a0(end)];
%                 else
%                     mitosis_end=a0(end);
%                 end
%                 
%                 num_mitosis_events = length(mitosis_begin);
%                 mitosis_begin2=[];
%                 mitosis_end2=[];
%                 for jj=1:length(mitosis_begin)
%                     mitosis_event=red_norm(mitosis_begin(jj):mitosis_end(jj));
%                     signal_drop=max(mitosis_event)-min(mitosis_event);
%                     if signal_drop>min_signal_drop
%                         mitosis_begin2 = [mitosis_begin2 mitosis_begin(jj)];
%                         mitosis_end2 = [mitosis_end2 mitosis_end(jj)];
%                     end
%                 end
%                 mitosis_begin=mitosis_begin2;
%                 mitosis_end=mitosis_end2;
%                 num_mitosis_events=length(mitosis_begin);
%             else
%                 num_mitosis_events=0;
%             end
%             
%             %find G1-S transition
%             b0 = find(green_Slope<thresh_steep);
%             c0 = find(red_Slope(b0)>0);
%             %add function to count all G1-S transitions
%             classified(b0(c0))=0.75;
%             c00=find(diff(b0(c0))>1);
%             if ~isempty(c0)
%                 G1_S_begin=[b0(c0(1)) b0(c0(c00+1))];
%                 G1_S_begin2=G1_S_begin(1);
%                 for ii=1:length(G1_S_begin)-1
%                     if G1_S_begin(ii+1)-G1_S_begin(ii)>time_interval_mitosis
%                         G1_S_begin2=[G1_S_begin2 G1_S_begin(ii+1)];
%                     else
%                         classified(G1_S_begin(ii):G1_S_begin(ii+1))=0.75;
%                     end
%                 end
%                 G1_S_begin=G1_S_begin2;
%                 if length(G1_S_begin)>1
%                     G1_S_end=[mitosis_begin(2:end) b0(c0(end))];
%                 else
%                     G1_S_end=b0(c0(end));
%                 end
%                 num_G1_S_events=length(G1_S_begin);
%             else
%                 num_G1_S_events=0;
%             end
%             % num_G1_S_events;
%             % num_mitosis_events;
%             if num_G1_S_events==0 && num_mitosis_events==0
%                 gr_mean=mean(green_norm);
%                 rd_mean=mean(red_norm(:));
%                 if gr_mean<rd_mean
%                     classified=ones(size(classified))*0.75;
%                 else
%                     classified=ones(size(classified))*0.5;
%                 end
%             elseif num_G1_S_events==0 && num_mitosis_events==1
%                 classified(1:mitosis_begin-1)=0.75;
%                 classified(mitosis_end+1:end)=0.5;
%             elseif num_G1_S_events==1 && num_mitosis_events==0
%                 classified(1:G1_S_begin-1)=0.5;
%                 classified(G1_S_end+1:end)=0.75;
%             elseif num_G1_S_events==1 && num_mitosis_events==1
%                 if G1_S_begin<mitosis_begin
%                     classified(1:G1_S_begin-1)=0.5;
%                     classified(G1_S_end+1:mitosis_begin-1)=0.75;
%                     classified(mitosis_end+1:end)=0.5;
%                 else
%                     classified(1:mitosis_begin-1)=0.75;
%                     classified(mitosis_end+1:G1_S_begin-1)=0.5;
%                     classified(G1_S_end+1:end)=0.75;
%                 end
%             end
%             % split region 4 into 5 different sections
%             a0=find(classified==0.75);
%             if ~isempty(a0)
%                 a00=green_norm(a0)>0;
%                 a0=a0(a00);
%             end
%             if ~isempty(a0)
%                 min4=(min(green_norm(a0)));
%                 b0=find(green_norm(a0)==min4);
%                 a0=a0(b0:end);
%                 max4=max(green_norm(a0));
%                 thresh=min4+0.1*(max4-min4);
%                 G2_Region=find(green_norm(a0)>thresh);
%                 if ~isempty(G2_Region)
%                     classified(a0(G2_Region(1):end))=1;
%                 end
%             end
%         end
        
        function classified = delete_begin_end(app, classified)
            % Function that deletes the begin and end of a certain
            % classification. It puts a zero for deleted
            % classification. (JWB)
            
            % Determine begin and end.
            first = classified(1);
            last = classified(end);
            
            % Get uncertain value
            U = app.phaseval(app.phases == 'Uncertain');
            
            % Loop over entries from second item
            for i=1:1:length(classified)
                if classified(i) ~= first
                    % A classification boundary is reached
                    break
                end
                classified(i) = U;
            end
            
            % Loop backwards over entries from second to last item
            for i=length(classified):-1:1
                if classified(i) ~= last
                    % A classification boundary is reached
                    break
                end
                classified(i) = U;
            end
        end
        
        function [green_channel, red_channel, white_channel, posX, posY, frames] = get_channels_JWB(app, current_track)
            % Function to get the channels from the NUM part of the excel
            % file. This is called by loading data.
            
            % Find the specific cell id
            aa = find(app.NUM(:,3) == current_track);
            
            % Create channels
            if ~isempty(aa)
                posX = app.NUM(aa,5);
                posY = app.NUM(aa,6);
                frames = app.NUM(aa,9);
                green_channel = app.NUM(aa,12);
                red_channel   = app.NUM(aa,13);
                white_channel = app.NUM(aa,14);
            end
        end
        
        function [green_norm, red_norm, white_norm, posX, posY, classification, frames] = get_channels_an(app, annotated_data, current_track, current_cell)
            % Function that gets the channels from the annotated_data for
            % a specific track and a specific cell (JWB). Output is
            % normalized between 0 and 1 for plotting.
            
            % Find the current track and cell
            aa = (annotated_data(:,1)==current_track) & (annotated_data(:,2)==current_cell);
            
            if ~isempty(aa)
                % Read annotated data
                green_channel  = annotated_data(aa, 3);
                red_channel    = annotated_data(aa, 4);
                white_channel  = annotated_data(aa, 5);
                posX           = annotated_data(aa, 6);
                posY           = annotated_data(aa, 7);
                classification = annotated_data(aa, 8);
                frames         = annotated_data(aa, 9);
                
                % Create normalized channels
                [green_norm, red_norm, white_norm] = norm_channels(app, green_channel, red_channel, white_channel);
            end
        end
        
        function params = get_params(app, handles)
            % function that gets the edit parameters from edit boxes (JWB)
            params.thresh_gradual        = str2double(get(handles.edit1,'string'));
            params.thresh_steep          = str2double(get(handles.edit2,'string'));
            params.time_interval_mitosis = str2double(get(handles.edit3,'string'));
            params.thresh_minima         = str2double(get(handles.edit4,'string'));
        end
        
        function update_plots(app, handles)
            % The color intensity plot is updated through this function,
            % edit this function for style changes to the plot (JWB)
            
            % Set the correct axes
            axes(handles.axes1)
            
            % Create the plot, starting with the right y-axis
            yyaxis right
            plot(handles.frames, handles.classified,'k', 'LineWidth', 1.5)
            ylim([-0.05, 1.05])
            yticks(app.phaseval)
            yticklabels(app.phases)
            set(gca,'YColor','k')
            
            % Left y-axis
            yyaxis left
            plot(handles.frames, handles.green_channel2,'-g', 'LineWidth', 1.5)
            hold on
            plot(handles.frames, handles.red_channel2,'-r', 'LineWidth', 1.5)
            plot(handles.frames, handles.white_channel2,'-c', 'LineWidth', 1.5)
            hold off
            ylim([-0.05, 1.05])
            set(gca,'YColor','k')
            
            % Set x-limits
            xlim([min(handles.frames)-2, max(handles.frames)+2])
            
            % Legend and labels
            legend('PIP-mVenus','Gem-mCherry','SiR-DNA','Classification',...
                'Location', 'NorthOutside', 'Orientation', 'horizontal')
            xlabel('Frame')
            ylabel('Relative intensity')
            
            % Grid settings, first off because of toggling minor grid.
            grid off
            grid on
            grid minor
        end
        
        function [green_norm, red_norm, white_norm] = norm_channels(app, green, red, white)
            % Normalize the channels.
            green_norm = normalize(green, 'norm', Inf);
            red_norm   = normalize(red, 'norm', Inf);
            white_norm = normalize(white, 'norm', Inf);
            
            % Filter?
            green_norm = medfilt1(green_norm, 5, 'truncate');
            red_norm   = medfilt1(red_norm, 5, 'truncate');
            white_norm = medfilt1(white_norm, 5, 'truncate');
        end
        
        function cellno = mitosis(app, frames)
            % Function that creates the cell number array for tracked
            % cells with mitosis.
            cellno = ones(length(frames), 1);
            
            % Set cell to 1 and frame to the first one
            current_cell = 1;
            frame0 = frames(1);
            
            % Loop over track to find different cells
            for j=2:length(frames)
                if frames(j) == frame0
                    current_cell = current_cell + 1;
                    cellno(j) = current_cell;
                else
                    frame0 = frames(j);
                    current_cell = 1;
                end
            end
        end
        
        function cropped_image = image2cell(app, full_image, x, y)
            % Crops the full ImageJ image to approximately around the cell.
            
            % Get size of image
            S = size(full_image);
            
            if app.transfer_coords == 1
                % Offset for cell
                dx = round(S(1)/app.ImageHeight .* app.CellOffset);
                
                % Transfer coordinates
                h = round(S(1)/app.ImageHeight .* y);
                w = round(S(2)/app.ImageWidth .* x);
            else
                dx = app.CellOffset;
                h = round(y);
                w = round(x);
            end
            
            % Check bounds
            if h-dx < 1
                hl = 1;
            else
                hl = h-dx;
            end
            
            if h+dx > S(1)
                hr = S(1);
            else
                hr = h+dx;
            end
            
            if w-dx < 1
                wl = 1;
            else
                wl = w-dx;
            end
            
            if w+dx > S(2)
                wr = S(2);
            else
                wr = w+dx;
            end
            
            % Crop image around cell
            cropped_image = full_image(hl:hr, wl:wr);
            
            % Adjust contrast
            cropped_image = imadjust(cropped_image, [0 app.ImageContrast]);
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FUCCI_V4_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function sets the base values in all edit boxes of the FUCCI GUI.
            set(handles.edit1,'string','0.005')
            set(handles.edit2,'string','-0.02')
            set(handles.edit3,'string','20')
            set(handles.edit4,'string', '0.03')%app.edit4.Value = "0.03";
            set(handles.listbox1,'string','1')
            set(handles.edit7,'string','1');
            set(handles.edit8,'string','10');
            set(handles.listbox2,'value',1);
            
            % Choose default command line output for FUCCI_V4
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function: ClassifieddataxlsxMenu, Save_data
        function SaveData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Check if data is loaded into the tool already
            if app.data_loaded == 0
                msgbox('Load data first.')
                return
            end
            
            % Function that saves the output in a .xlsx file (JWB)
            [filename, pathname] = uiputfile('*.xlsx','save file as..');
            
            % Jump back to GUI window
            figure(app.MainWindow)
            
            % Write the excel file
            if filename ~= 0
                annotated_data = handles.annotated_data;
                xlswrite([pathname filename], {'Track #', 'Cell #' , 'Green channel' , 'Red channel','White channel', 'posX', 'posY', 'Cell cycle stage', 'Frame number', 'Comment'}, 'Sheet1' , 'A1')
                xlswrite([pathname filename], annotated_data, 'Sheet1', 'A2');
                xlswrite([pathname filename], app.comments, 'Sheet1', 'J2')
                
                % Set logical data saved toggle
                app.data_saved = 1;
            else
                msgbox('Incorrect file selected')
            end
            guidata(hObject,handles);
        end

        % Value changed function: CellSelector
        function CellSelector_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Select current cell and track number.
            annotated_data=handles.annotated_data;
            current_cell=get(hObject,'value');
            current_track=handles.current_track;
            
            % Get channels for this track and cell
            [green_norm, red_norm, white_norm, posX, posY, classified, frames] = get_channels_an(app, handles.annotated_data, current_track, current_cell);
            
            % Save into handles
            handles.posX = posX;
            handles.posY = posY;
            handles.frames         = frames;
            handles.classified     = classified;
            handles.green_channel2 = green_norm;
            handles.red_channel2   = red_norm;
            handles.white_channel2 = white_norm;
            handles.annotated_data = annotated_data;
            
            % Update plots
            update_plots(app, handles)
            
            guidata(hObject,handles);
        end

        % Menu selected function: open_classified
        function open_classified_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Function that opens the excel file for already classified data. (JWB)
            [filename,pathname]=uigetfile('*.xlsx');
            
            % Jump back to GUI window
            figure(app.MainWindow)
            
            % Read excel if filename is supplied properly
            if filename ~= 0
                [data,~,~] = xlsread([pathname, filename]);
                handles.annotated_data = data;
                set(handles.popupmenu1,'string',num2str(unique(data(:,1))));
                
                % Set data_loaded class property
                app.data_loaded = 1;
            else
                msgbox('Incorrect file selected')
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: TrackSelector
        function TrackSelector_Callback(app, event)
            % The function that determines the callback from popupmenu1 (the selection
            % box for different cell tracks) (JWB)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Get data from handles
            annotated_data=handles.annotated_data;
            
            % Find current track and cell
            V=get(hObject,'Value');
            tracks = get(hObject,'String');
            current_track = str2double(tracks(V,:));
            current_cell = 1;
            
            % Get current channel out of annotated data
            [g_norm, r_norm, w_norm, posX, posY, classified, frames] = get_channels_an(app, annotated_data, current_track, current_cell);
            
            % Check for mitosis
            cells = max(annotated_data((annotated_data(:,1)==current_track),2));
            cellnames = cell(cells, 1);
            for i=1:cells
                cellnames(i) = {num2str(i)};
            end
            
            % Update CellSelector
            set(handles.listbox1,'value', 1);
            set(handles.listbox1,'string', cellnames);
            
            % Save into handles
            handles.posX = posX;
            handles.posY = posY;
            handles.frames = frames;
            handles.current_track  = current_track;
            handles.classified     = classified;
            handles.green_channel2 = g_norm;
            handles.red_channel2   = r_norm;
            handles.white_channel2 = w_norm;
            handles.annotated_data = annotated_data;
            
            % Update plots
            update_plots(app, handles)
            
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Check if data is loaded into the tool already
            if app.data_loaded == 0
                msgbox('Load data first.')
                return
            end
            
            % Function that updates the plots and manual classification (JWB)
            annotated_data = handles.annotated_data;
            current_track  = handles.current_track;
            current_cell   = get(handles.listbox1,'value');
            
            % Get values from edit7 and edit8 + round (JWB)
            start_value = round(str2double(get(handles.edit7,'string')));
            end_value   = round(str2double(get(handles.edit8,'string')));
            
            % Check values
            if start_value < min(handles.frames)
                msgbox(['Only edits between frame ', num2str(min(handles.frames)), ' and ', num2str(max(handles.frames)), ' are accepted.'])
                return
            end
            
            if end_value > max(handles.frames)
                msgbox(['Only edits between frame ', num2str(min(handles.frames)), ' and ', num2str(max(handles.frames)), ' are accepted.'])
                return
            end
            
            % Get phase from listbox2 (JWB)
            cycle_phase = get(handles.listbox2,'value');
                       
            % Change classification
            start_value = find(start_value == handles.frames);
            end_value = find(end_value == handles.frames);
            handles.classified(start_value:end_value) = app.phaseval(cycle_phase);
         
            % Update annotated_data
            aa = (annotated_data(:,1)==current_track) & (annotated_data(:,2)==current_cell);
            annotated_data(aa,8) = handles.classified;
            handles.annotated_data = annotated_data;
            
            % Update plots
            update_plots(app, handles)
            
            % Save handles
            guidata(hObject,handles);
        end

        % Menu selected function: ImagefilestiffMenu
        function ImagefilestiffMenuSelected(app, event)
            % Load tiff and save into handles or try app (JWB)
            
            % Function that opens the excel file for already classified
            % data. (JWB)
            [filename,pathname] = uigetfile('*.tiff;*.tif', 'Image file(*.tiff,*.tif)');
            
            % Jump back to GUI window
            figure(app.MainWindow)
            
            % Read image file if filename is supplied properly and save in
            % app
            if filename ~= 0
                % Disable tiff warnings
                warning('off','imageio:tiffmexutils:libtiffWarning')
                
                % Open TIFF
                app.Image = Tiff([pathname, filename],'r');
                app.ImageInfo = imfinfo([pathname, filename]);
                
                % Prompt popup with dimensions
                prompt = {'Coordinate scaling (0=No, 1=Yes)', 'Image height for scaling (micron):','Image width for scaling (micron):', ...
                    'Cell clipping (micron/pixels):', 'Brightness (between 0 (bright) and 1 (dark)):'};
                dlgtitle = 'Image loading settings (Reload to change!)';
                dims = [1 70];
                definput = {num2str(app.transfer_coords), num2str(app.ImageHeight), num2str(app.ImageWidth), num2str(app.CellOffset), num2str(app.ImageContrast)};
                answer = inputdlg(prompt, dlgtitle, dims, definput);
                
                % Jump back to GUI window
                figure(app.MainWindow)
                
                if length(answer) > 1
                    % Set settings
                    app.transfer_coords = str2double(answer{1});
                    app.ImageHeight = str2double(answer{2});
                    app.ImageWidth = str2double(answer{3});
                    app.CellOffset = str2double(answer{4});
                    app.ImageContrast = str2double(answer{5});
                
                    % Set image loaded boolean.
                    app.image_loaded = 1;
                else
                    msgbox('Please confirm tiff settings by reloading the tiff file.')
                end
            else
                msgbox('Incorrect file selected')
            end
        end

        % Menu selected function: RawdataxlsxcsvMenu
        function RawDataSelected(app, event)
            % Load data from csv file (JWB)
            
            % Create GUIDE-style callback args
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Open csv file
            [pathname, filename] = uigetfile('*csv;*.xlsx', 'Data file (*.csv, *.xlsx)');
            
            % Jump back to GUI window
            figure(app.MainWindow)
            
            % Proceed if file is selected correctly
            if filename ~= 0
                % Read the csv file
                app.NUM = readmatrix([filename, pathname]);
                
                % Set max number of frames
                app.max_frame = max(app.NUM(:,9));
                
                % Get individual track numbers
                tracks = unique((app.NUM(~isnan(app.NUM(:,3)),3)));
                tracksizes = zeros(length(tracks));
                
                % Set them in TrackSelector
                app.TrackSelector.Items = string(tracks);
                
                % Annotate the data
                annotated_data = [];
                params = get_params(app, handles);
                
                % For each track
                for i=1:length(tracks)
                    current_track = tracks(i);
                    
                    % Get channels
                    [green_channel, red_channel, white_channel, posX, posY, frames] = get_channels_JWB(app, current_track);
                    tracksizes(i) = length(green_channel);
                    
                    % Set cell numbers
                    if tracksizes(i) > app.max_frame
                        % With mitosis
                        cellno = mitosis(app, frames);
                        
                        % Preallocate classification
                        classified = zeros(size(green_channel));
                        
                        % For each cell number
                        for j=1:max(cellno)
                            % Pick the part of this particular cell number
                            green_cell = green_channel(cellno == j);
                            red_cell = red_channel(cellno == j);
                            white_cell = white_channel(cellno == j);
                            
                            % Normalize channels
                            [green_norm, red_norm, white_norm] = norm_channels(app, green_cell, red_cell, white_cell);
                        
                            % Classify stages
                            classified_cell = classify_stages(app, params, green_norm, red_norm, white_norm);
                        
                            if app.UncertaintyCheckbox.Value == 1
                                % Set begin and end to zero
                                classified_cell = delete_begin_end(app, classified_cell);
                            end
                            
                            % Store cell classification in complete
                            % classification
                            classified(cellno == j) = classified_cell;
                        end
                    else
                        % Without mitosis
                        cellno = ones(tracksizes(i), 1);
                        
                        % Normalize channels
                        [green_norm, red_norm, white_norm] = norm_channels(app, green_channel, red_channel, white_channel);
                    
                        % Classify stages
                        classified = classify_stages(app, params, green_norm, red_norm, white_norm);
                        
                        if app.UncertaintyCheckbox.Value == 1
                            % Set begin and end to zero
                            classified = delete_begin_end(app, classified);
                        end
                    end
                    
                    
                    % Annotate
                    S = size(classified);

                    annotated_data = [annotated_data; ones(S)*current_track,...
                        cellno, green_channel, ...
                        red_channel, white_channel, ...
                        posX, posY, classified, frames]; %#ok<AGROW>
                end
                
                % Save into handles
                handles.annotated_data = annotated_data;
                app.comments = cell(length(annotated_data), 1);
                
                % Set data_loaded class property
                app.data_loaded = 1;
            else
                msgbox('Incorrect file selected')
            end
        
            % Save data
            guidata(hObject,handles);
        end

        % Button pushed function: UpdateImage
        function UpdateImagePushed(app, event)
            % Update the image plot, with frame info and current track
            % information
            
            % Create GUIDE-style callback args
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % check if data is loaded already
            if app.image_loaded == 0
                msgbox('Load image tiff file first')
                return
            end
            
            if app.data_loaded == 0
                msgbox('Load data before updating image')
                return
            end
            
            % Change frame
            lastFrame = max(handles.frames);
            firstFrame = min(handles.frames);
            frame = round(get(app.FrameNumberImage,'value'));
            if frame < firstFrame || frame > lastFrame
                msgbox(['Select a frame between ', num2str(firstFrame),' and ', num2str(lastFrame)])
                return
            end
            
            % Set the SiR-DNA channel
            app.Image.setDirectory((frame+1)*3) % Compensate for the zero frame

            % Get posX and posY
            X = handles.posX(frame == handles.frames);
            Y = handles.posY(frame == handles.frames);
            
            % Set the correct axes
            axes(handles.axes2)
            cla(handles.axes2)
            
            % Update the plot
            imshow(image2cell(app, app.Image.read(), X, Y))
            
            % Save data
            guidata(hObject,handles);
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            % Function that executes if close button is clicked (JWB)
            dlgTitle = 'Close PIP-FUCCI tool';
            
            % A lot of switch case statements to handle different
            % situations using the boolean toggle properties of the app
            switch app.data_saved
                case 0
                    switch app.data_loaded
                        case 0
                            dlgQuestion = 'Do you wish to continue?';
                        case 1
                            dlgQuestion = ['Do you wish to continue?',...
                                newline ,'You did not save your data.'];
                    end
                case 1
                    dlgQuestion = 'Do you wish to continue?';
            end
            
            choice = questdlg(dlgQuestion,dlgTitle,'Yes','No', 'Yes');
            
            switch choice
                case 'Yes'
                    disp('')
                case 'No'
                    delete(app)
            end
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.FrameNumberImage.Value = app.FrameNumberImage.Value + 1;
            UpdateImagePushed(app, event)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.FrameNumberImage.Value = app.FrameNumberImage.Value - 1;
            UpdateImagePushed(app, event)
        end

        % Button pushed function: AddCommentButton
        function AddCommentButtonPushed(app, event)
            % Create GUIDE-style callback args
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            annotated_data = handles.annotated_data;            
            current_track  = handles.current_track;
            current_cell   = str2double(app.CellSelector.Value);
            current_frame  = app.FrameNumberImage.Value;

            % Find index
            index = annotated_data(:,2)==current_cell & annotated_data(:,1)==current_track & annotated_data(:,9)==current_frame;

            % Add comment
            app.comments(index) = {string(app.CommentEditField.Value)};
            
            % Save data
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'FUCCI_V5';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.MainWindow.Tag = 'figure1';
            app.LoadDataMenu = uimenu(app.MainWindow);
            app.LoadDataMenu.Text = 'Load data';
            app.LoadDataMenu.Tag = 'File';
            app.RawdataxlsxcsvMenu = uimenu(app.LoadDataMenu);
            app.RawdataxlsxcsvMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDataSelected, true);
            app.RawdataxlsxcsvMenu.Text = 'Raw data (.xlsx, .csv)';
            app.open_classified = uimenu(app.LoadDataMenu);
            app.open_classified.MenuSelectedFcn = createCallbackFcn(app, @open_classified_Callback, true);
            app.open_classified.Text = 'Classified data (.xlsx)';
            app.open_classified.Tag = 'open_classified';
            app.ImagefilestiffMenu = uimenu(app.LoadDataMenu);
            app.ImagefilestiffMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagefilestiffMenuSelected, true);
            app.ImagefilestiffMenu.Text = 'Image files (.tiff)';
            app.SavetofileMenu = uimenu(app.MainWindow);
            app.SavetofileMenu.Text = 'Save to file';
            app.ClassifieddataxlsxMenu = uimenu(app.SavetofileMenu);
            app.ClassifieddataxlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveData_Callback, true);
            app.ClassifieddataxlsxMenu.Text = 'Classified data (.xlsx)';
            app.SiRDNAimagePanel = uipanel(app.MainWindow);
            app.SiRDNAimagePanel.Title = 'SiR-DNA image';
            app.GridLayout = uigridlayout(app.SiRDNAimagePanel);
            app.AddCommentButton = uibutton(app.GridLayout, 'push');
            app.AddCommentButton.ButtonPushedFcn = createCallbackFcn(app, @AddCommentButtonPushed, true);
            app.AddCommentButton.Text = 'Add';
            app.UpdateImage = uibutton(app.GridLayout, 'push');
            app.UpdateImage.ButtonPushedFcn = createCallbackFcn(app, @UpdateImagePushed, true);
            app.UpdateImage.Text = 'Update';
            app.ForwardButton = uibutton(app.GridLayout, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = '->';
            app.FrameNumberImage = uieditfield(app.GridLayout, 'numeric');
            app.FrameLabel = uilabel(app.GridLayout);
            app.FrameLabel.Text = 'Frame:';
            app.CommentEditField = uieditfield(app.GridLayout, 'text');
            app.CommentEditFieldLabel = uilabel(app.GridLayout);
            app.CommentEditFieldLabel.Text = 'Comment:';
            app.BackButton = uibutton(app.GridLayout, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '<-';
            app.ImagePlot = uiaxes(app.GridLayout);
            app.ImagePlot.Tag = 'axes2';
            app.CellSelector = uilistbox(app.MainWindow);
            app.CellSelector.Items = {'Listbox'};
            app.CellSelector.ValueChangedFcn = createCallbackFcn(app, @CellSelector_Callback, true);
            app.CellSelector.Tag = 'listbox1';
            app.CellSelector.Value = 'Listbox';
            app.Save_data = uibutton(app.MainWindow, 'push');
            app.Save_data.ButtonPushedFcn = createCallbackFcn(app, @SaveData_Callback, true);
            app.Save_data.Tag = 'Save_data';
            app.Save_data.Text = 'Save to file';
            app.TrackSelector = uidropdown(app.MainWindow);
            app.TrackSelector.Items = {'Pop-up Menu'};
            app.TrackSelector.ValueChangedFcn = createCallbackFcn(app, @TrackSelector_Callback, true);
            app.TrackSelector.Tag = 'popupmenu1';
            app.TrackSelector.Value = 'Pop-up Menu';
            app.AutomaticcharacterisationsettingsPanel = uipanel(app.MainWindow);
            app.AutomaticcharacterisationsettingsPanel.Title = 'Automatic characterisation settings';
            app.GridACSPanel = uigridlayout(app.AutomaticcharacterisationsettingsPanel);
            app.edit1 = uieditfield(app.GridACSPanel, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = 'Edit Text';
            app.edit2 = uieditfield(app.GridACSPanel, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = 'Edit Text';
            app.edit3 = uieditfield(app.GridACSPanel, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = 'Edit Text';
            app.text2 = uilabel(app.GridACSPanel);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Gradual threshold';
            app.text3 = uilabel(app.GridACSPanel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Steep threshold';
            app.text4 = uilabel(app.GridACSPanel);
            app.text4.Tag = 'text4';
            app.text4.Text = 'M Interval (frames)';
            app.UncertaintyCheckbox = uicheckbox(app.GridACSPanel);
            app.UncertaintyCheckbox.Text = 'Add uncertainty to begin and end.';
            app.UncertaintyCheckbox.Value = true;
            app.ChangebeforeloadingdataLabel = uilabel(app.GridACSPanel);
            app.ChangebeforeloadingdataLabel.Text = 'Change before loading data!';
            app.edit4 = uieditfield(app.GridACSPanel, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = 'Edit Text';
            app.text4_2 = uilabel(app.GridACSPanel);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = 'Minima threshold';
            app.ManualcharacterisationupdatePanel = uipanel(app.MainWindow);
            app.ManualcharacterisationupdatePanel.Title = 'Manual characterisation update';
            app.pushbutton4 = uibutton(app.ManualcharacterisationupdatePanel, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Update';
            app.text8 = uilabel(app.ManualcharacterisationupdatePanel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'end value';
            app.text7 = uilabel(app.ManualcharacterisationupdatePanel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'start value';
            app.edit8 = uieditfield(app.ManualcharacterisationupdatePanel, 'text');
            app.edit8.Tag = 'edit8';
            app.edit8.Value = 'Edit Text';
            app.edit7 = uieditfield(app.ManualcharacterisationupdatePanel, 'text');
            app.edit7.Tag = 'edit7';
            app.edit7.Value = 'Edit Text';
            app.listbox2 = uilistbox(app.ManualcharacterisationupdatePanel);
            app.listbox2.Items = {'Uncertain', 'M', 'G1', 'S', 'G2'};
            app.listbox2.Tag = 'listbox2';
            app.listbox2.Value = 'M';
            app.SelecttracknumberLabel = uilabel(app.MainWindow);
            app.SelecttracknumberLabel.Text = 'Select track number:';
            app.SelectcellnumberLabel = uilabel(app.MainWindow);
            app.SelectcellnumberLabel.Text = 'Select cell number:';
            app.IntensityPlot = uiaxes(app.MainWindow);
            app.IntensityPlot.Tag = 'axes1';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FUCCI_V5(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)FUCCI_V4_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end